classdef resultView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        resultViewFigure     matlab.ui.Figure
        Panel                matlab.ui.container.Panel
        MVPALogo             matlab.ui.control.Image
        versionLabel         matlab.ui.control.Label
        MVPAlabToolboxLabel  matlab.ui.control.Label
        resultFilePanel      matlab.ui.container.Panel
        loadResultButton     matlab.ui.control.Button
        ResultfileLabel      matlab.ui.control.Label
        loadResultField      matlab.ui.control.EditField
        plotResutsButton     matlab.ui.control.Button
        statisticsPanel      matlab.ui.container.Panel
        plotAboveCluster     matlab.ui.control.CheckBox
        plotBelowCluster     matlab.ui.control.CheckBox
        TabGroup             matlab.ui.container.TabGroup
        MVPATab              matlab.ui.container.Tab
        SlidingfilterTab     matlab.ui.container.Tab
        colorPanel           matlab.ui.container.Panel
        ColorschemeLabel     matlab.ui.control.Label
        colorSchemeSelector  matlab.ui.control.DropDown
        inverColorScheme     matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        cfg
        graph
    end
    
    methods (Access = private)
        
        %% HELPER - UpdateGUI
        function updateGUI(app)
        end
        
        %% HELPER - Get focus
        function getFocus(app)
            app.resultViewFigure.Visible = 'off';
            app.resultViewFigure.Visible = 'on';
        end
        
        %% HELPER - Load results
        function loadResults(app,path,file)
            % Load study and result file:
            temp = load([path file]);
            app.cfg = temp.cfg;
            assignin('base','cfg',temp.cfg);
            assignin('base','result',temp.result);
            
            % Try to import stats data to base workspace:
            if exist([path filesep 'stats.mat'],'file')
                temp = load([path filesep 'stats.mat']);
                assignin('base','stats',temp.stats);
                app.enableStats('on');
            else
                app.enableStats('off');
            end
            
            % Try to import permuted maps to base workspace:
            if exist([path filesep 'permaps.mat'],'file')
                temp = load([path filesep 'permaps.mat']);
                assignin('base','permaps',temp.permaps);
            end
        end
        
        %% HELPER - Update graph structure
        function updateGraph (app)
            assignin('base','graph',app.graph);
        end
        
        %% HELPER - Enable stats panel
        function enableStats (app,value)
            app.plotAboveCluster.Enable = value;
            app.plotBelowCluster.Enable = value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Init graph structure and assign to base workspace:
            app.graph = mvpalab_plotinit();
            assignin('base','graph',app.graph);
        end

        % Button pushed function: plotResutsButton
        function plotResutsButtonPushed(app, event)
            evalin("base", 'plotController');
        end

        % Value changed function: colorSchemeSelector
        function colorSchemeSelectorValueChanged(app, event)
            app.graph.colorMap = getfield(app.graph.grads,...
                app.colorSchemeSelector.Value);
            colormap(app.graph.colorMap);
        end

        % Button pushed function: loadResultButton
        function loadResultButtonButtonPushed(app, event)
            % Disable load and plot button:
            app.loadResultButton.Text = 'Loading data...';
            app.loadResultButton.Enable = 'off';
            app.plotResutsButton.Enable = 'off';
            
            % File selector:
            openDir = fullfile(getenv('HOME'),'Desktop');
            [file,path] = uigetfile([openDir '/result.mat'], ...
                'Select a result.mat file');
            app.getFocus();
            
            if file
                if strcmp(file,'result.mat')
                    % Update text field
                    app.loadResultField.Value = [path file];
                    
                    % Load data in the base workspace:
                    evalin("base",'clear all');
                    app.loadResults(path,file);
                    app.updateGUI();
                    app.getFocus();
                else
                    uialert(app.resultViewFigure,...
                        'Please, select a correct result.mat file',...
                        'Warning');
                end
            else
                % Clear dir:
                app.loadResultField.Value = '';
            end
            
            % Enable load button
            app.loadResultButton.Text = 'Select file';
            app.loadResultButton.Enable = 'on';
            app.plotResutsButton.Enable = 'on';
        end

        % Value changed function: plotAboveCluster
        function plotAboveClusterValueChanged(app, event)
            app.graph.stats.above = app.plotAboveCluster.Value;
            app.updateGraph();
        end

        % Value changed function: plotBelowCluster
        function plotBelowClusterValueChanged(app, event)
            app.graph.stats.below = app.plotBelowCluster.Value;
            app.updateGraph();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.resultViewFigure = uifigure('Visible', 'off');
            app.resultViewFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.resultViewFigure);
            app.MVPALogo = uiimage(app.Panel);
            app.MVPALogo.ImageSource = 'mvpalab.png';
            app.versionLabel = uilabel(app.Panel);
            app.versionLabel.Text = 'BETA v0.1';
            app.MVPAlabToolboxLabel = uilabel(app.Panel);
            app.MVPAlabToolboxLabel.Text = 'MVPAlab Toolbox';
            app.resultFilePanel = uipanel(app.resultViewFigure);
            app.resultFilePanel.Title = 'Result file location:';
            app.loadResultButton = uibutton(app.resultFilePanel, 'push');
            app.loadResultButton.ButtonPushedFcn = createCallbackFcn(app, @loadResultButtonButtonPushed, true);
            app.loadResultButton.Text = 'Select file';
            app.ResultfileLabel = uilabel(app.resultFilePanel);
            app.ResultfileLabel.Text = 'Result file:';
            app.loadResultField = uieditfield(app.resultFilePanel, 'text');
            app.loadResultField.Editable = 'off';
            app.plotResutsButton = uibutton(app.resultViewFigure, 'push');
            app.plotResutsButton.ButtonPushedFcn = createCallbackFcn(app, @plotResutsButtonPushed, true);
            app.plotResutsButton.Enable = 'off';
            app.plotResutsButton.Text = 'Plot results';
            app.statisticsPanel = uipanel(app.resultViewFigure);
            app.statisticsPanel.Title = 'Statistics:';
            app.plotAboveCluster = uicheckbox(app.statisticsPanel);
            app.plotAboveCluster.ValueChangedFcn = createCallbackFcn(app, @plotAboveClusterValueChanged, true);
            app.plotAboveCluster.Text = 'Highlight above chance significant clusters';
            app.plotBelowCluster = uicheckbox(app.statisticsPanel);
            app.plotBelowCluster.ValueChangedFcn = createCallbackFcn(app, @plotBelowClusterValueChanged, true);
            app.plotBelowCluster.Text = 'Highlight below chance significant clusters';
            app.TabGroup = uitabgroup(app.resultViewFigure);
            app.MVPATab = uitab(app.TabGroup);
            app.MVPATab.Title = 'MVPA';
            app.SlidingfilterTab = uitab(app.TabGroup);
            app.SlidingfilterTab.Title = 'Sliding filter';
            app.colorPanel = uipanel(app.resultViewFigure);
            app.colorPanel.Title = 'Color configuration:';
            app.ColorschemeLabel = uilabel(app.colorPanel);
            app.ColorschemeLabel.Text = 'Color scheme:';
            app.colorSchemeSelector = uidropdown(app.colorPanel);
            app.colorSchemeSelector.Items = {'MVPAlab', 'Sun', 'Mercury', 'Venus', 'Earth', 'Mars', 'Jupiter', 'Saturn', 'Uranus', 'Neptune'};
            app.colorSchemeSelector.ItemsData = {'mvpalab', 'sun', 'mercury', 'venus', 'earth', 'mars', 'jupiter', 'saturn', 'uranus', 'neptune', ''};
            app.colorSchemeSelector.ValueChangedFcn = createCallbackFcn(app, @colorSchemeSelectorValueChanged, true);
            app.colorSchemeSelector.Value = 'mvpalab';
            app.inverColorScheme = uicheckbox(app.colorPanel);
            app.inverColorScheme.Text = 'Invert color scheme';
            % Show the figure after all components are created
            app.resultViewFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = resultView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.resultViewFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.resultViewFigure)
        end
    end
end